classdef MotionTrackingDisplay_Syndesmose < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DisplayTImestepLabel  matlab.ui.control.Label
        TimestepSlider        matlab.ui.control.Slider
        TimestepSliderLabel   matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        positionFib  % 3D data for plotting Fib
        positionTib  % 3D data for plotting Tib
        positionTib_PoI  % 3D data for plotting Tib Point of Interest
        hFib         % Handle to the Fib plot
        hTib         % Handle to the Tib plot
        hTib_PoI         % Handle to the Tib Point of Interest plot
        numTimesteps  % Number of timesteps (number of .mat files loaded)
        dataDir      % Directory for input data
    end
    
    % Function to load data and initialize the plot
    methods (Access = private)

        function loadData(app)
             % Load all .mat files for Fib and Tib segments and store positions
            timestep = 1;
            positionDataFib = {};
            positionDataTib = {};
        
            while true
                % Load Fib segment data
                timestamp_label_fib = sprintf('Fib_Seg_%04d.mat', timestep);
                fileNameFib = fullfile(app.dataDir, timestamp_label_fib); % Construct full file path for Fib
        
                if ~isfile(fileNameFib)
                    break; % Stop loading if file does not exist
                end
        
                loadedDataFib = load(fileNameFib);
                varNamesFib = fieldnames(loadedDataFib);
                positionDataFib{timestep} = loadedDataFib.(varNamesFib{1});  % Access the first variable in the structure
        
                % Load corresponding Tib segment data
                timestamp_label_tib = sprintf('Tib_Seg_%04d.mat', timestep);
                fileNameTib = fullfile(app.dataDir, timestamp_label_tib); % Construct full file path for Tib
        
                if ~isfile(fileNameTib)
                    break; % Stop loading if file does not exist
                end
        
                loadedDataTib = load(fileNameTib);
                varNamesTib = fieldnames(loadedDataTib);
                positionDataTib{timestep} = loadedDataTib.(varNamesTib{1});  % Access the first variable in the structure
                
                % Load corresponding Tib Point of Interst data
                timestamp_label_tib_PoI = sprintf('Tib_PoI_%04d.mat', timestep);
                fileNameTib_PoI = fullfile(app.dataDir, timestamp_label_tib_PoI); % Construct full file path for Tib
        
                if ~isfile(fileNameTib_PoI)
                    break; % Stop loading if file does not exist
                end
        
                loadedDataTib_PoI = load(fileNameTib_PoI);
                % disp(loadedDataTib_PoI)
                % asdfds
                varNamesTib_PoI = fieldnames(loadedDataTib_PoI);
                positionDataTib_PoI{timestep} = loadedDataTib_PoI.(varNamesTib_PoI{1});  % Access the first variable in the structure
                                
                % Update timestamp
                timestep = timestep + 1;
            end
        
            % Store positions for Fib
            app.numTimesteps = length(positionDataFib);
            app.positionFib = cell(1, app.numTimesteps);
            for t = 1:app.numTimesteps
                app.positionFib{t} = positionDataFib{t};
            end
            
            % Store positions for Tib
            app.positionTib = cell(1, app.numTimesteps);
            for t = 1:app.numTimesteps
                app.positionTib{t} = positionDataTib{t};
            end
            
            % Store positions for Tib Point of Interest
            app.positionTib_PoI = cell(1, app.numTimesteps);
            for t = 1:app.numTimesteps
                app.positionTib_PoI{t} = positionDataTib_PoI{t};
            end
        end

        function initializePlot(app)
            % Initialize plot with the first timestep for both Fib and Tib
            % Plot Fib segment data
            if ~isempty(app.positionFib{1}) % Check if there are points to plot
                app.hFib = plot3(app.UIAxes, ...
                                 app.positionFib{1}(1, :), ...
                                 app.positionFib{1}(2, :), ...
                                 app.positionFib{1}(3, :), '.', 'DisplayName', 'Fibula');
                hold(app.UIAxes, 'on');  % Hold the current plot
            end
            
            % Plot Tib segment data
            if ~isempty(app.positionTib{1}) % Check if there are points to plot
                app.hTib = plot3(app.UIAxes, ...
                                 app.positionTib{1}(1, :), ...
                                 app.positionTib{1}(2, :), ...
                                 app.positionTib{1}(3, :), '.', 'DisplayName', 'Tibia');
            end
            
            % Plot Tib PoI data
            if ~isempty(app.positionTib_PoI{1}) % Check if there are points to plot
                app.hTib_PoI = plot3(app.UIAxes, ...
                                 app.positionTib_PoI{1}(1, :), ...
                                 app.positionTib_PoI{1}(2, :), ...
                                 app.positionTib_PoI{1}(3, :), 'r.', 'MarkerSize', 15, 'DisplayName', 'Tibia_PoI');
            end
            hold(app.UIAxes, 'off');  % Release the hold
        
            app.DisplayTImestepLabel.Text = 'Timestep: 1';
            
            % Set consistent axes limits across all timesteps
            xLimits = [min(cellfun(@(x) min(x(1, :), [], 'omitnan'), app.positionFib)), ...
                       min(cellfun(@(x) min(x(1, :), [], 'omitnan'), app.positionTib)), ...
                       max(cellfun(@(x) max(x(1, :), [], 'omitnan'), app.positionFib)), ...
                       max(cellfun(@(x) max(x(1, :), [], 'omitnan'), app.positionTib))];
            
            yLimits = [min(cellfun(@(x) min(x(2, :), [], 'omitnan'), app.positionFib)), ...
                       min(cellfun(@(x) min(x(2, :), [], 'omitnan'), app.positionTib)), ...
                       max(cellfun(@(x) max(x(2, :), [], 'omitnan'), app.positionFib)), ...
                       max(cellfun(@(x) max(x(2, :), [], 'omitnan'), app.positionTib))];
        
            zLimits = [min(cellfun(@(x) min(x(3, :), [], 'omitnan'), app.positionFib)), ...
                       min(cellfun(@(x) min(x(3, :), [], 'omitnan'), app.positionTib)), ...
                       max(cellfun(@(x) max(x(3, :), [], 'omitnan'), app.positionFib)), ...
                       max(cellfun(@(x) max(x(3, :), [], 'omitnan'), app.positionTib))];
        
            % Set the axes to equal proportions
            axis(app.UIAxes, 'equal');  % Ensure equal scaling of axes
        
            % Set the axes limits
            xlim(app.UIAxes, [min(xLimits), max(xLimits)]);
            ylim(app.UIAxes, [min(yLimits), max(yLimits)]);
            zlim(app.UIAxes, [min(zLimits), max(zLimits)]);
        
            % Configure the slider
            app.TimestepSlider.Limits = [1, app.numTimesteps];
            app.TimestepSlider.Value = 1;
            app.TimestepSlider.MajorTicks = 1:app.numTimesteps;
        
            legend(app.UIAxes, 'show'); % Show the legend
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Prompt user to select data directory
            app.dataDir = uigetdir('', 'Select Data Directory');
            if isequal(app.dataDir, 0)
                uialert(app.UIFigure, 'No directory selected', 'Error');
                return; % Exit startup if no directory is selected
            end
            
            % Load data and initialize the 3D plot
            loadData(app);
            if app.numTimesteps > 0
                initializePlot(app);
            else
                uialert(app.UIFigure, 'No data files found', 'Error');
            end
        end

        % Value changed function: TimestepSlider
        function TimestepSliderValueChanged(app, event)
            % timeIndex = round(app.TimestepSlider.Value);  % Get the slider value
            % 
            % % Update the 3D plot to show the new timestep
            % set(app.h, 'XData', app.position(1, :, timeIndex), ...
            %            'YData', app.position(2, :, timeIndex), ...
            %            'ZData', app.position(3, :, timeIndex));
            % 
            % % Update the label to show the current timestep
            % app.DisplayTImestepLabel.Text = ['Timestep: ', num2str(timeIndex)];
        end

        % Value changing function: TimestepSlider
        function TimestepSliderValueChanging(app, event)
             timeIndex = round(event.Value);  % Get the slider value

            % Update the 3D plot to show the new timestep for both Fib and Tib
            if ~isempty(app.hFib)  % Check if Fib points exist
                set(app.hFib, 'XData', app.positionFib{timeIndex}(1, :), ...
                              'YData', app.positionFib{timeIndex}(2, :), ...
                              'ZData', app.positionFib{timeIndex}(3, :));
            end
            
            if ~isempty(app.hTib)  % Check if Tib points exist
                set(app.hTib, 'XData', app.positionTib{timeIndex}(1, :), ...
                              'YData', app.positionTib{timeIndex}(2, :), ...
                              'ZData', app.positionTib{timeIndex}(3, :));
            end
            
            if ~isempty(app.hTib_PoI)  % Check if Tib points exist
                set(app.hTib_PoI, 'XData', app.positionTib_PoI{timeIndex}(1, :), ...
                              'YData', app.positionTib_PoI{timeIndex}(2, :), ...
                              'ZData', app.positionTib_PoI{timeIndex}(3, :));
            end
            
            % Update the label to show the current timestep
            app.DisplayTImestepLabel.Text = ['Timestep: ', num2str(timeIndex)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tibia and Fibula')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TimestepSliderLabel = uilabel(app.UIFigure);
            app.TimestepSliderLabel.Text = 'Timestep';
            app.TimestepSlider = uislider(app.UIFigure);
            app.TimestepSlider.ValueChangedFcn = createCallbackFcn(app, @TimestepSliderValueChanged, true);
            app.TimestepSlider.ValueChangingFcn = createCallbackFcn(app, @TimestepSliderValueChanging, true);
            app.DisplayTImestepLabel = uilabel(app.UIFigure);
            app.DisplayTImestepLabel.Text = 'DisplayTImestep';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionTrackingDisplay_Syndesmose

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end